classdef TensorView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotButtonGroup                 matlab.ui.container.ButtonGroup
        MoleculeandtensorButton         matlab.ui.control.RadioButton
        MoleculeButton                  matlab.ui.control.RadioButton
        TensorButton                    matlab.ui.control.RadioButton
        TensorstyleButtonGroup          matlab.ui.container.ButtonGroup
        OvaloidButton                   matlab.ui.control.RadioButton
        EllipsoidButton                 matlab.ui.control.RadioButton
        Lamp                            matlab.ui.control.Lamp
        TensorcolorLabel                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LoadpdborxyzButton              matlab.ui.control.Button
        TensorvaluemolecularframeLabel  matlab.ui.control.Label
        TensorcoordinatesLabel          matlab.ui.control.Label
        Get3DmodelButton                matlab.ui.control.Button
        TensortransparencySlider        matlab.ui.control.Slider
        s_12EditField                   matlab.ui.control.NumericEditField
        TensortransparencySliderLabel   matlab.ui.control.Label
        s_22EditField                   matlab.ui.control.NumericEditField
        s_12EditFieldLabel              matlab.ui.control.Label
        s_32EditField                   matlab.ui.control.NumericEditField
        s_22EditFieldLabel              matlab.ui.control.Label
        s_11EditField                   matlab.ui.control.NumericEditField
        s_32EditFieldLabel              matlab.ui.control.Label
        s_21EditField                   matlab.ui.control.NumericEditField
        s_11EditFieldLabel              matlab.ui.control.Label
        s_31EditField                   matlab.ui.control.NumericEditField
        s_21EditFieldLabel              matlab.ui.control.Label
        s_13EditField                   matlab.ui.control.NumericEditField
        s_31EditFieldLabel              matlab.ui.control.Label
        s_23EditField                   matlab.ui.control.NumericEditField
        s_13EditFieldLabel              matlab.ui.control.Label
        s_33EditField                   matlab.ui.control.NumericEditField
        s_23EditFieldLabel              matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        s_33EditFieldLabel              matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        zEditFieldLabel                 matlab.ui.control.Label
        TensorscalingEditField          matlab.ui.control.NumericEditField
        RSlider                         matlab.ui.control.Slider
        TensorscalingEditFieldLabel     matlab.ui.control.Label
        GSlider                         matlab.ui.control.Slider
        RSliderLabel                    matlab.ui.control.Label
        BSlider                         matlab.ui.control.Slider
        GSliderLabel                    matlab.ui.control.Label
        MaxbondlengthEditField          matlab.ui.control.NumericEditField
        BSliderLabel                    matlab.ui.control.Label
        MaxbondlengthEditFieldLabel     matlab.ui.control.Label
        AdditionaltensorsCheckBox       matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        EuleranglesTab                  matlab.ui.container.Tab
        RotationschemeButtonGroup       matlab.ui.container.ButtonGroup
        ActiveZYZButton                 matlab.ui.control.RadioButton
        PassiveZYZButton                matlab.ui.control.RadioButton
        ActiveZXZButton                 matlab.ui.control.RadioButton
        PassiveZXZButton                matlab.ui.control.RadioButton
        MolecularframeEulerButton       matlab.ui.control.Button
        MolecularframeEulerButton_2     matlab.ui.control.Button
        AlphaEditFieldLabel             matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        BetaEditFieldLabel              matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        GammaEditFieldLabel             matlab.ui.control.Label
        GammaEditField                  matlab.ui.control.NumericEditField
        PAS_11EditFieldLabel            matlab.ui.control.Label
        PAS_11EditField                 matlab.ui.control.NumericEditField
        PAS_22EditFieldLabel            matlab.ui.control.Label
        PAS_22EditField                 matlab.ui.control.NumericEditField
        PAS_33EditFieldLabel            matlab.ui.control.Label
        PAS_33EditField                 matlab.ui.control.NumericEditField
        ShieldingShiftButton            matlab.ui.control.Button
        CSrefppmEditField               matlab.ui.control.NumericEditField
        CSrefppmEditFieldLabel          matlab.ui.control.Label
        EuleranglesLabel                matlab.ui.control.Label
        PrincipalaxistensorLabel        matlab.ui.control.Label
        PAStensororderButtonGroup       matlab.ui.container.ButtonGroup
        AscendingButton                 matlab.ui.control.RadioButton
        DescendingButton                matlab.ui.control.RadioButton
        AbsascendingButton              matlab.ui.control.RadioButton
        ButtonGroup                     matlab.ui.container.ButtonGroup
        RadiansButton                   matlab.ui.control.RadioButton
        DegreesButton                   matlab.ui.control.RadioButton
        EqAngles                        matlab.ui.control.Table
        EquivalentanglesLabel           matlab.ui.control.Label
        PlotreferenceframeButton        matlab.ui.control.Button
        AngleselectionDropDownLabel     matlab.ui.control.Label
        AngleselectionDropDown          matlab.ui.control.DropDown
        Tensorselection1DropDownLabel_4  matlab.ui.control.Label
        ReferenceframebrightnessSliderLabel  matlab.ui.control.Label
        ReferenceframebrightnessSlider  matlab.ui.control.Slider
        DipolartensorTab                matlab.ui.container.Tab
        GetdipolartensorButton          matlab.ui.control.Button
        Atom1Label                      matlab.ui.control.Label
        xEditField_2Label               matlab.ui.control.Label
        xEditField_2                    matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        yEditField_2                    matlab.ui.control.NumericEditField
        zEditField_2Label               matlab.ui.control.Label
        zEditField_2                    matlab.ui.control.NumericEditField
        Atom2Label                      matlab.ui.control.Label
        xEditField_3Label               matlab.ui.control.Label
        xEditField_3                    matlab.ui.control.NumericEditField
        yEditField_3Label               matlab.ui.control.Label
        yEditField_3                    matlab.ui.control.NumericEditField
        zEditField_3Label               matlab.ui.control.Label
        zEditField_3                    matlab.ui.control.NumericEditField
        MagneticdipolartensorLabel      matlab.ui.control.Label
        RelativetensorsTab              matlab.ui.container.Tab
        RotationschemeButtonGroup_2     matlab.ui.container.ButtonGroup
        ActiveZYZButton_2               matlab.ui.control.RadioButton
        PassiveZYZButton_2              matlab.ui.control.RadioButton
        ActiveZXZButton_2               matlab.ui.control.RadioButton
        PassiveZXZButton_2              matlab.ui.control.RadioButton
        Relativeanglesoftensor1intheframeoftensor2Button  matlab.ui.control.Button
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        RadiansButton_2                 matlab.ui.control.RadioButton
        DegreesButton_2                 matlab.ui.control.RadioButton
        EqRot                           matlab.ui.control.Table
        EquivalentrelativetensorsLabel  matlab.ui.control.Label
        AlphaEditField_2Label           matlab.ui.control.Label
        AlphaEditField_2                matlab.ui.control.NumericEditField
        BetaEditField_2Label            matlab.ui.control.Label
        BetaEditField_2                 matlab.ui.control.NumericEditField
        GammaEditField_2Label           matlab.ui.control.Label
        GammaEditField_2                matlab.ui.control.NumericEditField
        SaverelativeanglesdatButton     matlab.ui.control.Button
        PAStensororder1ButtonGroup      matlab.ui.container.ButtonGroup
        AscendingButton_2               matlab.ui.control.RadioButton
        DescendingButton_2              matlab.ui.control.RadioButton
        AbsascendingButton_2            matlab.ui.control.RadioButton
        PAStensororder2ButtonGroup      matlab.ui.container.ButtonGroup
        AscendingButton_3               matlab.ui.control.RadioButton
        DescendingButton_3              matlab.ui.control.RadioButton
        AbsascendingButton_3            matlab.ui.control.RadioButton
        ReferenceframesTab              matlab.ui.container.Tab
        RefTable                        matlab.ui.control.Table
        LoadreferenceframesdatButton    matlab.ui.control.Button
        PlottablereferenceframesButton  matlab.ui.control.Button
        EqAngles_2                      matlab.ui.control.Table
        AngleselectionDropDown_2Label   matlab.ui.control.Label
        AngleselectionDropDown_2        matlab.ui.control.DropDown
        RefselectionDropDownLabel       matlab.ui.control.Label
        RefselectionDropDown            matlab.ui.control.DropDown
        EquivalentanglesLabel_2         matlab.ui.control.Label
        PlotreferenceframeButton_2      matlab.ui.control.Button
        Tensorselection1DropDownLabel_5  matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        ExportwrlButton                 matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        LoadtensordatButton             matlab.ui.control.Button
        TensorTable                     matlab.ui.control.Table
        TensorListButton                matlab.ui.control.Button
        TensorListButton_2              matlab.ui.control.Button
        SavetensordatButton             matlab.ui.control.Button
        Tensorselection1DropDownLabel   matlab.ui.control.Label
        Tensorselection1DropDown        matlab.ui.control.DropDown
        Tensorselection2DropDownLabel   matlab.ui.control.Label
        Tensorselection2DropDown        matlab.ui.control.DropDown
        PlotalltensorsButton            matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ExportforpdbButton              matlab.ui.control.Button
        PlotallreferenceframesButton    matlab.ui.control.Button
        ExportglbButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        fullfilename = ''; % Description
    end
    
    properties (Access = public)
        fig % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            funfolder = fullfile(pwd,'functions');
            addpath(genpath(funfolder));
            funfolder2 = fullfile(funfolder,'matlab2glb');
            addpath(genpath(funfolder2));
            
            app.s_11EditFieldLabel.Interpreter = 'latex';
            app.s_21EditFieldLabel.Interpreter = 'latex';
            app.s_31EditFieldLabel.Interpreter = 'latex';
            app.s_12EditFieldLabel.Interpreter = 'latex';
            app.s_22EditFieldLabel.Interpreter = 'latex';
            app.s_32EditFieldLabel.Interpreter = 'latex';
            app.s_13EditFieldLabel.Interpreter = 'latex';
            app.s_23EditFieldLabel.Interpreter = 'latex';
            app.s_33EditFieldLabel.Interpreter = 'latex';
            app.s_11EditFieldLabel.Text = '$A_{11}$';
            app.s_21EditFieldLabel.Text = '$A_{21}$';
            app.s_31EditFieldLabel.Text = '$A_{31}$';
            app.s_12EditFieldLabel.Text = '$A_{12}$';
            app.s_22EditFieldLabel.Text = '$A_{22}$';
            app.s_32EditFieldLabel.Text = '$A_{32}$';
            app.s_13EditFieldLabel.Text = '$A_{13}$';
            app.s_23EditFieldLabel.Text = '$A_{23}$';
            app.s_33EditFieldLabel.Text = '$A_{33}$';
            app.Lamp.Color = [app.RSlider.Value,app.GSlider.Value,app.BSlider.Value];
            app.PAS_11EditFieldLabel.Interpreter = 'latex';
            app.PAS_11EditFieldLabel.Text = 'PAS$_{11}$';
            app.PAS_22EditFieldLabel.Interpreter = 'latex';
            app.PAS_22EditFieldLabel.Text = 'PAS$_{22}$';
            app.PAS_33EditFieldLabel.Interpreter = 'latex';
            app.PAS_33EditFieldLabel.Text = 'PAS$_{33}$';
            
            app.AlphaEditFieldLabel.Interpreter = 'latex';
            app.AlphaEditFieldLabel.Text = '$\alpha$';
            app.BetaEditFieldLabel.Interpreter = 'latex';
            app.BetaEditFieldLabel.Text = '$\beta$';
            app.GammaEditFieldLabel.Interpreter = 'latex';
            app.GammaEditFieldLabel.Text = '$\gamma$';
            app.AlphaEditField_2Label.Interpreter = 'latex';
            app.AlphaEditField_2Label.Text = '$\alpha$';
            app.BetaEditField_2Label.Interpreter = 'latex';
            app.BetaEditField_2Label.Text = '$\beta$';
            app.GammaEditField_2Label.Interpreter = 'latex';
            app.GammaEditField_2Label.Text = '$\gamma$';
            format short
            app.ExportwrlButton.Enable = 'off';
            app.ExportglbButton.Enable = 'off';
            app.TensorTable.RowName = {'11'; '12'; '13';'21'; '22'; '23';'31'; '32'; '33'; 'X(Å)'; 'Y(Å)'; 'Z(Å)'; 'Scale'; 'R',; 'G',; 'B'};
            app.EqRot.RowName = {'1','2','3','4','5','6','7','8','9','10','11','12','13','14','15','16'};
            app.EqRot.Data = zeros(16,3);
            app.EqAngles.RowName = {'1','2','3','4'};
            app.EqAngles.Data = zeros(4,3);
            Tensor = [app.s_11EditField.Value,app.s_12EditField.Value,app.s_13EditField.Value,app.s_21EditField.Value,app.s_22EditField.Value,app.s_23EditField.Value,app.s_31EditField.Value,app.s_32EditField.Value,app.s_33EditField.Value, app.xEditField.Value,app.yEditField.Value,app.zEditField.Value, app.TensorscalingEditField.Value, app.RSlider.Value,app.GSlider.Value,app.BSlider.Value];
            app.TensorTable.Data = Tensor';
            %app.TensorTable.CollumnName.Value = {};
        end

        % Button pushed function: Get3DmodelButton
        function Get3DmodelButtonPushed(app, event)
            app.Get3DmodelButton.Enable = 'off';
            pause(0.1)
            try
                Fname = app.fullfilename;
                
                if Fname == ""
                    app.TensorButton.Value = true;
                end
                
                s11 = app.s_11EditField.Value;
                s21 = app.s_21EditField.Value;
                s31 = app.s_31EditField.Value;
                s12 = app.s_12EditField.Value;
                s22 = app.s_22EditField.Value;
                s32 = app.s_32EditField.Value;
                s13 = app.s_13EditField.Value;
                s23 = app.s_23EditField.Value;
                s33 = app.s_33EditField.Value;
                
                x = app.xEditField.Value;
                y = app.yEditField.Value;
                z = app.zEditField.Value;
                Scale = app.TensorscalingEditField.Value;
                Trans = app.TensortransparencySlider.Value;
                R = app.RSlider.Value;
                G = app.GSlider.Value;
                B = app.BSlider.Value;
                
                Bond = app.MaxbondlengthEditField.Value;
                
                if app.OvaloidButton.Value
                    OvEl = "ovaloid";
                end
                if app.EllipsoidButton.Value
                    OvEl = "ellipsoid";
                end
                if app.MoleculeandtensorButton.Value
                    MolTen = 0;
                end
                if app.MoleculeButton.Value
                    MolTen = 1;
                end
                if app.TensorButton.Value
                    MolTen = 2;
                end
                MoreT = app.AdditionaltensorsCheckBox.Value;
                Tensor = [s11 s12 s13; s21 s22 s23; s31 s32 s33];
                TensorViewApp(Fname,Tensor,x,y,z,Scale,Trans,R,G,B,OvEl,MolTen,Bond,MoreT)
                app.fig = gcf;
                app.Get3DmodelButton.Enable = 'on';
                app.ExportwrlButton.Enable = 'on';
                app.ExportglbButton.Enable = 'on';
                
            catch
                app.Get3DmodelButton.Enable = 'on';
            end
        end

        % Button pushed function: LoadpdborxyzButton
        function LoadpdborxyzButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.pdb;*.xyz'});% add ur extension post period within parathesis.
            app.fullfilename = fullfile(pathname,filename);
            app.Label.Text = {filename};
            if contains(app.Label.Text, '.pdb')
                %app.MaxbondlengthEditFieldLabel ;
                %set(app.MaxbondlengthEditField, 'Enable', 'off');
            end % [file,path] = uiputfile
            app.MoleculeandtensorButton.Value = true;
        end

        % Value changing function: RSlider
        function RSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp.Color = [changingValue,app.GSlider.Value,app.BSlider.Value];
        end

        % Value changing function: GSlider
        function GSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp.Color = [app.RSlider.Value,changingValue,app.BSlider.Value];
        end

        % Value changing function: BSlider
        function BSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp.Color = [app.RSlider.Value,app.GSlider.Value,changingValue];
        end

        % Value changed function: AdditionaltensorsCheckBox
        function AdditionaltensorsCheckBoxValueChanged(app, event)
            value = app.AdditionaltensorsCheckBox.Value;
            if value == true
                app.TensorButton.Value = true;
                set(app.PlotButtonGroup, 'Enable', 'off');
            elseif value == false
                set(app.PlotButtonGroup, 'Enable', 'on');
            end
        end

        % Button pushed function: GetdipolartensorButton
        function GetdipolartensorButtonPushed(app, event)
            V1 = [app.xEditField_2.Value, app.yEditField_2.Value, app.zEditField_2.Value];
            V2 = [app.xEditField_3.Value, app.yEditField_3.Value, app.zEditField_3.Value];
            DT = CreateDipolar(V1,V2);
            
            
            app.s_11EditField.Value = DT(1,1);
            app.s_21EditField.Value = DT(2,1);
            app.s_31EditField.Value = DT(3,1);
            app.s_12EditField.Value = DT(1,2);
            app.s_22EditField.Value = DT(2,2);
            app.s_32EditField.Value = DT(3,2);
            app.s_13EditField.Value = DT(1,3);
            app.s_23EditField.Value = DT(2,3);
            app.s_33EditField.Value = DT(3,3);
            
            app.xEditField.Value = V1(1);
            app.yEditField.Value = V1(2);
            app.zEditField.Value = V1(3);
            
        end

        % Callback function
        function CSAButtonGroupSelectionChanged(app, event)
            
        end

        % Button pushed function: MolecularframeEulerButton
        function MolecularframeEulerButtonPushed(app, event)
            s11 = app.s_11EditField.Value;
            s21 = app.s_21EditField.Value;
            s31 = app.s_31EditField.Value;
            s12 = app.s_12EditField.Value;
            s22 = app.s_22EditField.Value;
            s32 = app.s_32EditField.Value;
            s13 = app.s_13EditField.Value;
            s23 = app.s_23EditField.Value;
            s33 = app.s_33EditField.Value;
            Tensor = [s11 s12 s13; s21 s22 s23; s31 s32 s33];
            
            if app.ActiveZYZButton.Value
                Mode = "AZYZ";
            end
            if app.PassiveZYZButton.Value
                Mode = "PZYZ";
            end
            if app.ActiveZXZButton.Value
                Mode = "AZXZ";
            end
            if app.PassiveZXZButton.Value
                Mode = "PZXZ";
            end
            
            if app.AscendingButton.Value
                Order = "Ascending";
            end
            if app.DescendingButton.Value
                Order = "Descending";
            end
            if app.AbsascendingButton.Value
                Order = "Absascending";
            end
            %app.MolecularframeEulerButton.Enable = false;
            %try
            [Euler,PAS] = MFtoEuler(Tensor,Mode,Order);
            %catch
            app.MolecularframeEulerButton.Enable = true;
            %end
            app.MolecularframeEulerButton.Enable = true;
            
            RelList = EquivAngles(Euler(1),Euler(2),Euler(3),Mode);
            
            app.EqAngles.RowName = {'1','2','3','4'};
            if app.RadiansButton.Value
                Euler = Euler;
                RelList = RelList;
            end
            if app.DegreesButton.Value
                Euler = Euler*180/pi;
                RelList = RelList*180/pi;
            end
            app.EqAngles.Data = RelList;
            
            app.AlphaEditField.Value = Euler(1);
            app.BetaEditField.Value = Euler(2);
            app.GammaEditField.Value = Euler(3);
            
            app.PAS_11EditField.Value = PAS(1);
            app.PAS_22EditField.Value = PAS(2);
            app.PAS_33EditField.Value = PAS(3);
            
            app.PlotreferenceframeButton.Enable = 'on';
            
            for i = 1:4
                RowCell{i} = ['Angle ' num2str(i)];
                ItemsDataCell{i} = i;
            end
            
            app.AngleselectionDropDown.Items = RowCell;
            app.AngleselectionDropDown.ItemsData = ItemsDataCell;
            
        end

        % Button pushed function: MolecularframeEulerButton_2
        function MolecularframeEulerButton_2Pushed(app, event)
            Alpha = app.AlphaEditField.Value;
            Beta = app.BetaEditField.Value;
            Gamma = app.GammaEditField.Value;
            PAS = zeros(3,3);
            PAS(1,1) = app.PAS_11EditField.Value;
            PAS(2,2) = app.PAS_22EditField.Value;
            PAS(3,3) = app.PAS_33EditField.Value;
            if app.ActiveZYZButton.Value
                System = "AZYZ";
            end
            if app.PassiveZYZButton.Value
                System = "PZYZ";
            end
            if app.ActiveZXZButton.Value
                System = "AZXZ";
            end
            if app.PassiveZXZButton.Value
                System = "PZXZ";
            end
            
            if app.RadiansButton.Value
                
            end
            if app.DegreesButton.Value
                Alpha = Alpha*pi/180;
                Beta = Beta*pi/180;
                Gamma = Gamma*pi/180;
            end
            
            
            MF = RotateTensor(Alpha,Beta,Gamma,PAS,System);
            
            ZN = abs(MF)<10^(-15);
            MF(ZN) = 0;
            
            
            app.s_11EditField.Value = MF(1,1);
            app.s_21EditField.Value = MF(2,1);
            app.s_31EditField.Value = MF(3,1);
            app.s_12EditField.Value = MF(1,2);
            app.s_22EditField.Value = MF(2,2);
            app.s_32EditField.Value = MF(3,2);
            app.s_13EditField.Value = MF(1,3);
            app.s_23EditField.Value = MF(2,3);
            app.s_33EditField.Value = MF(3,3);
            
        end

        % Button pushed function: ShieldingShiftButton
        function ShieldingShiftButtonPushed(app, event)
            app.PAS_11EditField.Value = app.CSrefppmEditField.Value - app.PAS_11EditField.Value;
            app.PAS_22EditField.Value = app.CSrefppmEditField.Value - app.PAS_22EditField.Value;
            app.PAS_33EditField.Value = app.CSrefppmEditField.Value - app.PAS_33EditField.Value;
        end

        % Button pushed function: ExportwrlButton
        function ExportwrlButtonPushed(app, event)
            app.ExportwrlButton.Enable = 'off';
            pause(0.1)
            try
                [filename,path] = uiputfile('*.wrl');
                fig = app.fig;
                Exportsurfwrl(fig,filename,path);
            catch
                app.ExportwrlButton.Enable = 'on';
            end
            app.ExportwrlButton.Enable = 'on';
        end

        % Button pushed function: LoadtensordatButton
        function LoadtensordatButtonPushed(app, event)
            
            [filename,pathname] = uigetfile({'*.dat'});% add ur extension post period within parathesis.
            Tensorfullfilename = fullfile(pathname,filename);
            Tensors = LoadTensor(Tensorfullfilename);
            app.MoleculeandtensorButton.Value = true;
            app.TensorTable.RowName = {'11'; '12'; '13';'21'; '22'; '23';'31'; '32'; '33'; 'X(Å)'; 'Y(Å)'; 'Z(Å)'; 'Scale'; 'R'; 'G'; 'B'};
            Tsize = size(Tensors);
            ColCell = cell(Tsize(1),1);
            ItemsDataCell = cell(Tsize(1),1);
            for i = 1:Tsize(1)
                ColCell{i} = ['Tensor ' num2str(i)];
                ItemsDataCell{i} = i;
            end
            
            app.TensorTable.ColumnName = ColCell;
            app.TensorTable.Data = Tensors';
            
            app.Tensorselection1DropDown.Items = ColCell;
            app.Tensorselection1DropDown.ItemsData = ItemsDataCell;
            app.Tensorselection2DropDown.Items = ColCell;
            app.Tensorselection2DropDown.ItemsData = ItemsDataCell;
            
            if max(size(ColCell))>1
                app.Tensorselection2DropDown.Value = 2;
            end
            
            
        end

        % Button pushed function: TensorListButton
        function TensorListButtonPushed(app, event)
            Tensor = [app.s_11EditField.Value,app.s_12EditField.Value,app.s_13EditField.Value,app.s_21EditField.Value,app.s_22EditField.Value,app.s_23EditField.Value,app.s_31EditField.Value,app.s_32EditField.Value,app.s_33EditField.Value, app.xEditField.Value,app.yEditField.Value,app.zEditField.Value,app.TensorscalingEditField.Value,app.RSlider.Value,app.GSlider.Value,app.BSlider.Value];
            
            if isempty(app.TensorTable.Data)
                
                app.TensorTable.Data = Tensor';
                app.TensorTable.ColumnName = {'Tensor 1'};
                app.Tensorselection1DropDown.Items = {'Tensor 1'};
                app.Tensorselection1DropDown.ItemsData = 1;
                app.Tensorselection2DropDown.Items = {'Tensor 1'};
                app.Tensorselection2DropDown.ItemsData = 1;
                
            else
                TabS = size(app.TensorTable.Data);
                app.TensorTable.Data = [app.TensorTable.Data, Tensor'];
                for i = 1:(TabS(2)+1)
                    ColCell{i} = ['Tensor ' num2str(i)];
                    ItemsDataCell{i} = i;
                end
                app.TensorTable.ColumnName = ColCell;
                app.Tensorselection1DropDown.Items = ColCell;
                app.Tensorselection1DropDown.ItemsData = ItemsDataCell;
                app.Tensorselection2DropDown.Items = ColCell;
                app.Tensorselection2DropDown.ItemsData = ItemsDataCell;
            end
        end

        % Button pushed function: TensorListButton_2
        function TensorListButton_2Pushed(app, event)
            SelectedItem = app.Tensorselection1DropDown.Value;
            M = app.TensorTable.Data;
            app.s_11EditField.Value = M((1),SelectedItem);
            app.s_21EditField.Value = M((4),SelectedItem);
            app.s_31EditField.Value = M((7),SelectedItem);
            app.s_12EditField.Value = M((2),SelectedItem);
            app.s_22EditField.Value = M((5),SelectedItem);
            app.s_32EditField.Value = M((8),SelectedItem);
            app.s_13EditField.Value = M((3),SelectedItem);
            app.s_23EditField.Value = M((6),SelectedItem);
            app.s_33EditField.Value = M((9),SelectedItem);
            app.xEditField.Value = M((10),SelectedItem);
            app.yEditField.Value = M((11),SelectedItem);
            app.zEditField.Value = M((12),SelectedItem);
            app.TensorscalingEditField.Value = M((13),SelectedItem);
            if M((14),SelectedItem)>= 0
                app.RSlider.Value = M((14),SelectedItem);
            end
            if M((15),SelectedItem)>= 0
                app.GSlider.Value = M((15),SelectedItem);
            end
            if M((16),SelectedItem)>= 0
                app.BSlider.Value = M((16),SelectedItem);
            end
        end

        % Button pushed function: SavetensordatButton
        function SavetensordatButtonPushed(app, event)
            app.SavetensordatButton.Enable = 'off';
            pause(0.1)
            M = app.TensorTable.Data;

            try
                [file,path] = uiputfile('*.dat');
                fileName = fullfile(path,file);
                SaveTensor(fileName,M);
            catch
                app.SavetensordatButton.Enable = 'on';
            end
            app.SavetensordatButton.Enable = 'on';
        end

        % Button pushed function: 
        % Relativeanglesoftensor1intheframeoftensor2Button
        function Relativeanglesoftensor1intheframeoftensor2ButtonPushed(app, event)
            
            SelectedItem1 = app.Tensorselection1DropDown.Value;
            SelectedItem2 = app.Tensorselection2DropDown.Value;
            
            M = app.TensorTable.Data;
            
            Tensor1 = zeros(3,3);
            Tensor2 = zeros(3,3);
            
            
            Tensor1(1,1) = M((1),SelectedItem1);
            Tensor1(1,2) = M((2),SelectedItem1);
            Tensor1(1,3) = M((3),SelectedItem1);
            Tensor1(2,1) = M((4),SelectedItem1);
            Tensor1(2,2) = M((5),SelectedItem1);
            Tensor1(2,3) = M((6),SelectedItem1);
            Tensor1(3,1) = M((7),SelectedItem1);
            Tensor1(3,2) = M((8),SelectedItem1);
            Tensor1(3,3) = M((9),SelectedItem1);
            
            Tensor2(1,1) = M((1),SelectedItem2);
            Tensor2(1,2) = M((2),SelectedItem2);
            Tensor2(1,3) = M((3),SelectedItem2);
            Tensor2(2,1) = M((4),SelectedItem2);
            Tensor2(2,2) = M((5),SelectedItem2);
            Tensor2(2,3) = M((6),SelectedItem2);
            Tensor2(3,1) = M((7),SelectedItem2);
            Tensor2(3,2) = M((8),SelectedItem2);
            Tensor2(3,3) = M((9),SelectedItem2);
            %xx xy xz yx yy yz zx zy zz X(Å) Y(Å) Z(Å)
            
            
            if app.ActiveZYZButton_2.Value
                Mode = "AZYZ";
            end
            if app.PassiveZYZButton_2.Value
                Mode = "PZYZ";
            end
            if app.ActiveZXZButton_2.Value
                Mode = "AZXZ";
            end
            if app.PassiveZXZButton_2.Value
                Mode = "PZXZ";
            end
            if app.AscendingButton_2.Value
                Order1 = "Ascending";
            end
            if app.DescendingButton_2.Value
                Order1 = "Descending";
            end
            if app.AbsascendingButton_2.Value
                Order1 = "Absascending";
            end
            if app.AscendingButton_3.Value
                Order2 = "Ascending";
            end
            if app.DescendingButton_3.Value
                Order2 = "Descending";
            end
            if app.AbsascendingButton_3.Value
                Order2 = "Absascending";
            end
            
            [Euler] = RelativeAngles(Tensor1,Tensor2,Mode,Order1,Order2);
            
            
            app.EqRot.RowName = {'1','2','3','4','5','6','7','8','9','10','11','12','13','14','15','16'};
            RelList = EquivRelAngles(Euler(1),Euler(2),Euler(3),Mode);
            if app.RadiansButton_2.Value
                Euler = Euler;
                RelList = RelList;
            end
            if app.DegreesButton_2.Value
                Euler = Euler*180/pi;
                RelList = RelList*180/pi;
            end
            app.EqRot.Data = RelList;
            %catch
            app.Relativeanglesoftensor1intheframeoftensor2Button.Enable = true;
            %end
            
            app.Relativeanglesoftensor1intheframeoftensor2Button.Enable = true;
            app.AlphaEditField_2.Value = Euler(1);
            app.BetaEditField_2.Value = Euler(2);
            app.GammaEditField_2.Value = Euler(3);
            
        end

        % Button pushed function: SaverelativeanglesdatButton
        function SaverelativeanglesdatButtonPushed(app, event)
            app.SavetensordatButton.Enable = 'off';
            pause(0.1)
            M = app.EqRot.Data;
            
            try
                [file,path] = uiputfile('*.dat');
                fileName = fullfile(path,file);
                SaveRelAngles(fileName,M');
            catch
                app.SavetensordatButton.Enable = 'on';
            end
            app.SavetensordatButton.Enable = 'on';
        end

        % Button pushed function: PlotalltensorsButton
        function PlotalltensorsButtonPushed(app, event)
            app.PlotalltensorsButton.Enable = 'off';
            pause(0.1)
            try
                Fname = app.fullfilename;
                if Fname == ""
                    app.TensorButton.Value = true;
                end
                NTensor = numel(app.TensorTable.ColumnName);
                
                M = app.TensorTable.Data;
                TensorList = cell(NTensor,1);
                coord = cell(NTensor,1);
                ScaleList = cell(NTensor,1);
                RGBlist = cell(NTensor,1);
                for i = 1:NTensor
                    TensorList{i}(1,1) = M((1),i);
                    TensorList{i}(1,2) = M((2),i);
                    TensorList{i}(1,3) = M((3),i);
                    TensorList{i}(2,1) = M((4),i);
                    TensorList{i}(2,2) = M((5),i);
                    TensorList{i}(2,3) = M((6),i);
                    TensorList{i}(3,1) = M((7),i);
                    TensorList{i}(3,2) = M((8),i);
                    TensorList{i}(3,3) = M((9),i);
                    coord{i}(1) =  M((10),i);
                    coord{i}(2) =  M((11),i);
                    coord{i}(3) =  M((12),i);
                    if isnumeric(M((13),i))
                        if isnan(M((13),i))
                            ScaleList{i}(1) = NaN;
                        else
                            ScaleList{i}(1) =  M((13),i);
                        end
                    else
                        ScaleList{i}(1) = NaN;
                    end
                    RGBlist{i}(1) = M((14),i);
                    RGBlist{i}(2) = M((15),i);
                    RGBlist{i}(3) = M((16),i);
                end
                
                
                Trans = app.TensortransparencySlider.Value;
                
                Bond = app.MaxbondlengthEditField.Value;

                if app.OvaloidButton.Value
                    OvEl = "ovaloid";
                end
                if app.EllipsoidButton.Value
                    OvEl = "ellipsoid";
                end
                for NT = 1:NTensor
                    s11 = TensorList{NT}(1,1);
                    s21 = TensorList{NT}(2,1);
                    s31 = TensorList{NT}(3,1);
                    s12 = TensorList{NT}(1,2);
                    s22 = TensorList{NT}(2,2);
                    s32 = TensorList{NT}(3,2);
                    s13 = TensorList{NT}(1,3);
                    s23 = TensorList{NT}(2,3);
                    s33 = TensorList{NT}(3,3);
                    x = coord{NT}(1);
                    y = coord{NT}(2);
                    z = coord{NT}(3);
                    if isnumeric(ScaleList{NT}(1))
                        if isnan(ScaleList{NT}(1))
                           Scale = app.TensorscalingEditField.Value;
                        else
                           Scale =  ScaleList{NT}(1); 
                        end  
                    else
                        Scale = app.TensorscalingEditField.Value;
                    end
                    
                    if isnan(RGBlist{NT}(1))
                       R = app.RSlider.Value;
                    else
                       R = RGBlist{NT}(1);
                    end
                    
                    if isnan(RGBlist{NT}(2))
                       G = app.GSlider.Value;
                    else
                       G = RGBlist{NT}(2);
                    end
                    
                    if isnan(RGBlist{NT}(3))
                       B = app.BSlider.Value;
                    else
                       B = RGBlist{NT}(3);
                    end
                    Tensor = [s11 s12 s13; s21 s22 s23; s31 s32 s33];
                    if NT == 1
                        MolTen = 0;
                        if app.TensorButton.Value
                            MolTen = 2;
                        end
                        MoreT = 0;
                        TensorViewApp(Fname,Tensor,x,y,z,Scale,Trans,R,G,B,OvEl,MolTen,Bond,MoreT)
                    else
                        MolTen = 2;
                        MoreT = 1;
                        TensorViewApp(Fname,Tensor,x,y,z,Scale,Trans,R,G,B,OvEl,MolTen,Bond,MoreT)
                    end
                end
                
                app.fig = gcf;
                app.PlotalltensorsButton.Enable = 'on';
                app.ExportwrlButton.Enable = 'on';
            catch
                app.PlotalltensorsButton.Enable = 'on';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.TensorTable.Data = [];
        end

        % Button pushed function: PlotreferenceframeButton
        function PlotreferenceframeButtonPushed(app, event)
            SelectedItem1 = app.AngleselectionDropDown.Value;
            
            M = app.EqAngles.Data;
            
            Angle(1) = M(SelectedItem1,(1));
            Angle(2) = M(SelectedItem1,(2));
            Angle(3) = M(SelectedItem1,(3));
            
            if app.DegreesButton.Value
                Angle = Angle*pi/180;
            end
            
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            z = app.zEditField.Value;
            
            Scale = app.TensorscalingEditField.Value;
            Brightness = app.ReferenceframebrightnessSlider.Value;
            
            if app.ActiveZYZButton.Value
                Mode = "AZYZ";
            end
            if app.PassiveZYZButton.Value
                Mode = "PZYZ";
            end
            if app.ActiveZXZButton.Value
                Mode = "AZXZ";
            end
            if app.PassiveZXZButton.Value
                Mode = "PZXZ";
            end
            
            PlotRef(Angle,x,y,z,Scale,Brightness,Mode)
            app.fig = gcf;
        end

        % Selection changed function: RotationschemeButtonGroup
        function RotationschemeButtonGroupSelectionChanged(app, event)
            selectedButton = app.RotationschemeButtonGroup.SelectedObject;
            app.PlotreferenceframeButton.Enable = 'off';
        end

        % Selection changed function: PAStensororderButtonGroup
        function PAStensororderButtonGroupSelectionChanged(app, event)
            selectedButton = app.PAStensororderButtonGroup.SelectedObject;
            app.PlotreferenceframeButton.Enable = 'off';
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.PlotreferenceframeButton.Enable = 'off';
        end

        % Button pushed function: ExportforpdbButton
        function ExportforpdbButtonPushed(app, event)
            TensorList = app.TensorTable.Data;
            app.ExportforpdbButton.Enable = 'off';
            pause(0.1)
            
            try
                [file,path] = uiputfile('*.dat');
                fileName = fullfile(path,file);
                ExportforPDB(fileName,TensorList);
            catch
                app.ExportforpdbButton.Enable = 'on';
            end
            app.ExportforpdbButton.Enable = 'on';
        end

        % Button pushed function: PlotallreferenceframesButton
        function PlotallreferenceframesButtonPushed(app, event)
            app.PlotallreferenceframesButton.Enable = 'off';
            pause(0.1)
            try
            NTensor = numel(app.TensorTable.ColumnName);
            Brightness = app.ReferenceframebrightnessSlider.Value;
            if app.AscendingButton.Value
                Order = "Ascending";
            end
            if app.DescendingButton.Value
                Order = "Descending";
            end
            if app.AbsascendingButton.Value
                Order = "Absascending";
            end
            M = app.TensorTable.Data;
            TensorList = cell(NTensor,1);
            coord = cell(NTensor,1);
            ScaleList = cell(NTensor,1);
            for i = 1:NTensor
                    TensorList{i}(1,1) = M((1),i);
                    TensorList{i}(1,2) = M((2),i);
                    TensorList{i}(1,3) = M((3),i);
                    TensorList{i}(2,1) = M((4),i);
                    TensorList{i}(2,2) = M((5),i);
                    TensorList{i}(2,3) = M((6),i);
                    TensorList{i}(3,1) = M((7),i);
                    TensorList{i}(3,2) = M((8),i);
                    TensorList{i}(3,3) = M((9),i);
                    coord{i}(1) =  M((10),i);
                    coord{i}(2) =  M((11),i);
                    coord{i}(3) =  M((12),i);
                    if isnumeric(M((13),i))
                        if isnan(M((13),i))
                            ScaleList{i}(1) = NaN;
                        else
                            ScaleList{i}(1) =  M((13),i);
                        end
                    else
                        ScaleList{i}(1) = NaN;
                    end
             end
            
                for NT = 1:NTensor
                    x = coord{NT}(1);
                    y = coord{NT}(2);
                    z = coord{NT}(3);
                    if isnumeric(ScaleList{NT}(1))
                        if isnan(ScaleList{NT}(1))
                           Scale = app.TensorscalingEditField.Value;
                        else
                           Scale =  ScaleList{NT}(1); 
                        end  
                    else
                        Scale = app.TensorscalingEditField.Value;
                    end
                    PlotAllRef(TensorList{NT},x,y,z,Order,Scale,Brightness)
                end
                
            
            app.fig = gcf;
            
            app.PlotallreferenceframesButton.Enable = 'on';
            catch
                app.PlotallreferenceframesButton.Enable = 'on';
            end
            
        end

        % Button pushed function: LoadreferenceframesdatButton
        function LoadreferenceframesdatButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.dat'});% add ur extension post period within parathesis.
            Reffullfilename = fullfile(pathname,filename);
            Refframes = LoadRefFrames(Reffullfilename);
            app.RefTable.RowName = {'Alpha'; 'Beta'; 'Gamma';'X'; 'Y'; 'Z';'Scale'; 'Bright'; 'Mode'};
            Tsize = size(Refframes);
            ColCell = cell(Tsize(1),1);
            ItemsDataCell = cell(Tsize(1),1);
            for i = 1:Tsize(1)
                ColCell{i} = ['Tensor ' num2str(i)];
                ItemsDataCell{i} = i;
            end
            
            app.RefTable.ColumnName = ColCell;
            app.RefTable.Data = Refframes';
            
            Alpha = app.RefTable.Data{1,1}*pi/180;
            Beta = app.RefTable.Data{2,1}*pi/180;
            Gamma = app.RefTable.Data{3,1}*pi/180;
            Mode = app.RefTable.Data{9,1};
            
            RelList = EquivAngles(Alpha,Beta,Gamma,Mode);
            app.EqAngles_2.RowName = {'1','2','3','4'};
            
            app.EqAngles_2.Data = RelList*180/pi;
            app.RefselectionDropDown.Items = ColCell;
            app.RefselectionDropDown.ItemsData = ItemsDataCell;
            
            for i = 1:4
                RowCell{i} = ['Angle ' num2str(i)];
                ItemsDataCell{i} = i;
            end
            
            app.AngleselectionDropDown_2.Items = RowCell;
            app.AngleselectionDropDown_2.ItemsData = ItemsDataCell;
            
            app.PlotreferenceframeButton_2.Enable = true;
            app.PlottablereferenceframesButton.Enable = true;
        end

        % Button pushed function: PlottablereferenceframesButton
        function PlottablereferenceframesButtonPushed(app, event)
            app.PlottablereferenceframesButton.Enable = 'off';
            pause(0.1)
            try
            NTensor = numel(app.RefTable.ColumnName);
            disp(NTensor)
            RefTableCell = app.RefTable.Data;
            disp(RefTableCell)
            for i = 1:NTensor
                Angle = [RefTableCell{1,i} RefTableCell{2,i} RefTableCell{3,i}]*pi/180;
                x = RefTableCell{4,i};
                y = RefTableCell{5,i};
                z = RefTableCell{6,i};
                Scale = RefTableCell{7,i};
                Brightness = RefTableCell{8,i};
                Mode = RefTableCell{9,i};
                PlotRef(Angle,x,y,z,Scale,Brightness,Mode)
                app.fig = gcf;
            end
            
            app.PlottablereferenceframesButton.Enable = 'on';
            catch
                app.PlottablereferenceframesButton.Enable = 'on';
            end
        end

        % Value changed function: RefselectionDropDown
        function RefselectionDropDownValueChanged(app, event)
            RefSel = app.RefselectionDropDown.Value;
            Alpha = app.RefTable.Data{1,RefSel}*pi/180;
            Beta = app.RefTable.Data{2,RefSel}*pi/180;
            Gamma = app.RefTable.Data{3,RefSel}*pi/180;
            Mode = app.RefTable.Data{9,RefSel};
            
            RelList = EquivAngles(Alpha,Beta,Gamma,Mode);
            app.EqAngles_2.Data = RelList*180/pi;
        end

        % Button pushed function: PlotreferenceframeButton_2
        function PlotreferenceframeButton_2Pushed(app, event)
            AngleSel = app.AngleselectionDropDown_2.Value;
            RefSel = app.RefselectionDropDown.Value;
            Alpha = app.EqAngles_2.Data(AngleSel,1);
            Beta = app.EqAngles_2.Data(AngleSel,2);
            Gamma = app.EqAngles_2.Data(AngleSel,3);
            RefTableCell = app.RefTable.Data;
            Angle = [Alpha Beta Gamma]*pi/180;
            x = RefTableCell{4,RefSel};
            y = RefTableCell{5,RefSel};
            z = RefTableCell{6,RefSel};
            Scale = RefTableCell{7,RefSel};
            Brightness = RefTableCell{8,RefSel};
            Mode = RefTableCell{9,RefSel};
            
            PlotRef(Angle,x,y,z,Scale,Brightness,Mode)
        end

        % Button pushed function: ExportglbButton
        function ExportglbButtonPushed(app, event)
            app.ExportglbButton.Enable = 'off';
            pause(0.1)
            try
                [filename,path] = uiputfile('*.glb');
                fig = app.fig;
                Exportsurfglb(fig,filename,path);
            catch
                app.ExportglbButton.Enable = 'on';
            end
            app.ExportglbButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotButtonGroup.Title = 'Plot';
            app.MoleculeandtensorButton = uiradiobutton(app.PlotButtonGroup);
            app.MoleculeandtensorButton.Text = 'Molecule and tensor';
            app.MoleculeandtensorButton.Value = true;
            app.MoleculeButton = uiradiobutton(app.PlotButtonGroup);
            app.MoleculeButton.Text = 'Molecule';
            app.TensorButton = uiradiobutton(app.PlotButtonGroup);
            app.TensorButton.Text = 'Tensor';
            app.TensorstyleButtonGroup = uibuttongroup(app.UIFigure);
            app.TensorstyleButtonGroup.Tooltip = {'Switches between the two tensor visualization versions.'};
            app.TensorstyleButtonGroup.Title = 'Tensor style';
            app.OvaloidButton = uiradiobutton(app.TensorstyleButtonGroup);
            app.OvaloidButton.Text = 'Ovaloid';
            app.OvaloidButton.Value = true;
            app.EllipsoidButton = uiradiobutton(app.TensorstyleButtonGroup);
            app.EllipsoidButton.Text = 'Ellipsoid';
            app.Lamp = uilamp(app.UIFigure);
            app.TensorcolorLabel = uilabel(app.UIFigure);
            app.TensorcolorLabel.Text = 'Tensor color';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.LoadpdborxyzButton = uibutton(app.UIFigure, 'push');
            app.LoadpdborxyzButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpdborxyzButtonPushed, true);
            app.LoadpdborxyzButton.Text = 'Load .pdb or .xyz';
            app.TensorvaluemolecularframeLabel = uilabel(app.UIFigure);
            app.TensorvaluemolecularframeLabel.Text = 'Tensor value (molecular frame)';
            app.TensorcoordinatesLabel = uilabel(app.UIFigure);
            app.TensorcoordinatesLabel.Text = 'Tensor coordinates (Å)';
            app.Get3DmodelButton = uibutton(app.UIFigure, 'push');
            app.Get3DmodelButton.ButtonPushedFcn = createCallbackFcn(app, @Get3DmodelButtonPushed, true);
            app.Get3DmodelButton.Tooltip = {'The figure can take a few seconds to load.'; ''; 'If matlab is installed, you can find aditional camera options in the tool bar.'};
            app.Get3DmodelButton.Text = 'Get 3D model';
            app.TensortransparencySlider = uislider(app.UIFigure);
            app.TensortransparencySlider.Limits = [0.2 1];
            app.TensortransparencySlider.MajorTicks = [0.2 0.36 0.52 0.68 0.84 1];
            app.TensortransparencySlider.MajorTickLabels = {'0.2', '0.4', '0.5', '0.6', '0.8', '1'};
            app.TensortransparencySlider.Value = 0.5;
            app.s_12EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_12EditField.ValueDisplayFormat = '%.2f';
            app.s_12EditField.Value = -60.302;
            app.TensortransparencySliderLabel = uilabel(app.UIFigure);
            app.TensortransparencySliderLabel.Text = {'Tensor '; 'transparency'};
            app.s_22EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_22EditField.ValueDisplayFormat = '%.2f';
            app.s_22EditField.Value = -23.0881;
            app.s_12EditFieldLabel = uilabel(app.UIFigure);
            app.s_12EditFieldLabel.Text = 's_12';
            app.s_32EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_32EditField.ValueDisplayFormat = '%.2f';
            app.s_32EditField.Value = -28.2399;
            app.s_22EditFieldLabel = uilabel(app.UIFigure);
            app.s_22EditFieldLabel.Text = 's_22';
            app.s_11EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_11EditField.ValueDisplayFormat = '%.2f';
            app.s_11EditField.Value = -5.9766;
            app.s_32EditFieldLabel = uilabel(app.UIFigure);
            app.s_32EditFieldLabel.Text = 's_32';
            app.s_21EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_21EditField.ValueDisplayFormat = '%.2f';
            app.s_21EditField.Value = -65.5206;
            app.s_11EditFieldLabel = uilabel(app.UIFigure);
            app.s_11EditFieldLabel.Text = 's_11';
            app.s_31EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_31EditField.ValueDisplayFormat = '%.2f';
            app.s_31EditField.Value = -9.5073;
            app.s_21EditFieldLabel = uilabel(app.UIFigure);
            app.s_21EditFieldLabel.Text = 's_21';
            app.s_13EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_13EditField.ValueDisplayFormat = '%.2f';
            app.s_13EditField.Value = -10.8928;
            app.s_31EditFieldLabel = uilabel(app.UIFigure);
            app.s_31EditFieldLabel.Text = 's_31';
            app.s_23EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_23EditField.ValueDisplayFormat = '%.2f';
            app.s_23EditField.Value = -25.2372;
            app.s_13EditFieldLabel = uilabel(app.UIFigure);
            app.s_13EditFieldLabel.Text = 's_13';
            app.s_33EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_33EditField.ValueDisplayFormat = '%.2f';
            app.s_33EditField.Value = 56.2779;
            app.s_23EditFieldLabel = uilabel(app.UIFigure);
            app.s_23EditFieldLabel.Text = 's_23';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.ValueDisplayFormat = '%.3f';
            app.xEditField.Value = -4.299848;
            app.s_33EditFieldLabel = uilabel(app.UIFigure);
            app.s_33EditFieldLabel.Text = 's_33';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.ValueDisplayFormat = '%.3f';
            app.yEditField.Value = -0.400088;
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditField.ValueDisplayFormat = '%.3f';
            app.zEditField.Value = -0.033384;
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.TensorscalingEditField = uieditfield(app.UIFigure, 'numeric');
            app.TensorscalingEditField.Tooltip = {'Makes the tensor larger or smaller. 1 equals to 1 Ångström normalization for the largest tensor component.'};
            app.TensorscalingEditField.Value = 1.4;
            app.RSlider = uislider(app.UIFigure);
            app.RSlider.Limits = [0 1];
            app.RSlider.ValueChangingFcn = createCallbackFcn(app, @RSliderValueChanging, true);
            app.RSlider.Value = 1;
            app.TensorscalingEditFieldLabel = uilabel(app.UIFigure);
            app.TensorscalingEditFieldLabel.Text = 'Tensor scaling';
            app.GSlider = uislider(app.UIFigure);
            app.GSlider.Limits = [0 1];
            app.GSlider.ValueChangingFcn = createCallbackFcn(app, @GSliderValueChanging, true);
            app.GSlider.Value = 0.5;
            app.RSliderLabel = uilabel(app.UIFigure);
            app.RSliderLabel.Text = 'R';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [0 1];
            app.BSlider.ValueChangingFcn = createCallbackFcn(app, @BSliderValueChanging, true);
            app.GSliderLabel = uilabel(app.UIFigure);
            app.GSliderLabel.Text = 'G';
            app.MaxbondlengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxbondlengthEditField.Tooltip = {'Used for .xyz, and .pdb without connection lists.'; 'Ignored when .pdb includes a connection list.'};
            app.MaxbondlengthEditField.Value = 1.6;
            app.BSliderLabel = uilabel(app.UIFigure);
            app.BSliderLabel.Text = 'B';
            app.MaxbondlengthEditFieldLabel = uilabel(app.UIFigure);
            app.MaxbondlengthEditFieldLabel.Tooltip = {'Max bond length is used for: ".xyz" and ".pdb" without a connection list. '};
            app.MaxbondlengthEditFieldLabel.Text = 'Max bond length (Å)';
            app.AdditionaltensorsCheckBox = uicheckbox(app.UIFigure);
            app.AdditionaltensorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdditionaltensorsCheckBoxValueChanged, true);
            app.AdditionaltensorsCheckBox.Tooltip = {'Adds additional tensors when Get 3D model is pressed.'};
            app.AdditionaltensorsCheckBox.Text = 'Additional tensors';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EuleranglesTab = uitab(app.TabGroup);
            app.EuleranglesTab.Title = 'Euler angles';
            app.RotationschemeButtonGroup = uibuttongroup(app.EuleranglesTab);
            app.RotationschemeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RotationschemeButtonGroupSelectionChanged, true);
            app.RotationschemeButtonGroup.Tooltip = {'Active rotation solutions: {a,b,g+Pi}, {a+Pi,Pi-b,Pi-g}, and {a+Pi,Pi-b,2Pi-g}'; 'Passive rotation solutions: {a+Pi,b,g }, {Pi-a,Pi-b,Pi+g}, and {2Pi-a,Pi-b,Pi+g}'};
            app.RotationschemeButtonGroup.Title = 'Rotation scheme';
            app.ActiveZYZButton = uiradiobutton(app.RotationschemeButtonGroup);
            app.ActiveZYZButton.Text = 'Active Z-Y-Z';
            app.ActiveZYZButton.Value = true;
            app.PassiveZYZButton = uiradiobutton(app.RotationschemeButtonGroup);
            app.PassiveZYZButton.Text = 'Passive Z-Y-Z';
            app.ActiveZXZButton = uiradiobutton(app.RotationschemeButtonGroup);
            app.ActiveZXZButton.Text = 'Active Z-X-Z';
            app.PassiveZXZButton = uiradiobutton(app.RotationschemeButtonGroup);
            app.PassiveZXZButton.Text = 'Passive Z-X-Z';
            app.MolecularframeEulerButton = uibutton(app.EuleranglesTab, 'push');
            app.MolecularframeEulerButton.ButtonPushedFcn = createCallbackFcn(app, @MolecularframeEulerButtonPushed, true);
            app.MolecularframeEulerButton.Tooltip = {'Translates molecular frame tensor to PAS tensor and Euler angles.'; ''; '*Axially symmetric tensors solutions are not unique.'};
            app.MolecularframeEulerButton.Text = ' Molecular frame -->  Euler';
            app.MolecularframeEulerButton_2 = uibutton(app.EuleranglesTab, 'push');
            app.MolecularframeEulerButton_2.ButtonPushedFcn = createCallbackFcn(app, @MolecularframeEulerButton_2Pushed, true);
            app.MolecularframeEulerButton_2.Tooltip = {'Uses Euler angles and Principal axis tensor entry fields.'};
            app.MolecularframeEulerButton_2.Text = 'Molecular frame <-- Euler';
            app.AlphaEditFieldLabel = uilabel(app.EuleranglesTab);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.EuleranglesTab, 'numeric');
            app.AlphaEditField.ValueDisplayFormat = '%.2f';
            app.BetaEditFieldLabel = uilabel(app.EuleranglesTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.EuleranglesTab, 'numeric');
            app.BetaEditField.ValueDisplayFormat = '%.2f';
            app.GammaEditFieldLabel = uilabel(app.EuleranglesTab);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.EuleranglesTab, 'numeric');
            app.GammaEditField.ValueDisplayFormat = '%.2f';
            app.PAS_11EditFieldLabel = uilabel(app.EuleranglesTab);
            app.PAS_11EditFieldLabel.Text = 'PAS_11';
            app.PAS_11EditField = uieditfield(app.EuleranglesTab, 'numeric');
            app.PAS_11EditField.ValueDisplayFormat = '%.2f';
            app.PAS_22EditFieldLabel = uilabel(app.EuleranglesTab);
            app.PAS_22EditFieldLabel.Text = 'PAS_22';
            app.PAS_22EditField = uieditfield(app.EuleranglesTab, 'numeric');
            app.PAS_22EditField.ValueDisplayFormat = '%.2f';
            app.PAS_33EditFieldLabel = uilabel(app.EuleranglesTab);
            app.PAS_33EditFieldLabel.Text = 'PAS_33';
            app.PAS_33EditField = uieditfield(app.EuleranglesTab, 'numeric');
            app.PAS_33EditField.ValueDisplayFormat = '%.2f';
            app.ShieldingShiftButton = uibutton(app.EuleranglesTab, 'push');
            app.ShieldingShiftButton.ButtonPushedFcn = createCallbackFcn(app, @ShieldingShiftButtonPushed, true);
            app.ShieldingShiftButton.Tooltip = {'Shift = CS ref - Shielding'; ''; 'Switches between chemical shielding/shift anisotropy (NMR spectroscopy)'};
            app.ShieldingShiftButton.Text = 'Shielding <---> Shift';
            app.CSrefppmEditField = uieditfield(app.EuleranglesTab, 'numeric');
            app.CSrefppmEditField.ValueDisplayFormat = '%.2f';
            app.CSrefppmEditFieldLabel = uilabel(app.EuleranglesTab);
            app.CSrefppmEditFieldLabel.Text = 'CS ref (ppm)';
            app.EuleranglesLabel = uilabel(app.EuleranglesTab);
            app.EuleranglesLabel.Text = 'Euler angles';
            app.PrincipalaxistensorLabel = uilabel(app.EuleranglesTab);
            app.PrincipalaxistensorLabel.Text = 'Principal axis tensor';
            app.PAStensororderButtonGroup = uibuttongroup(app.EuleranglesTab);
            app.PAStensororderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PAStensororderButtonGroupSelectionChanged, true);
            app.PAStensororderButtonGroup.Title = 'PAS tensor order';
            app.AscendingButton = uiradiobutton(app.PAStensororderButtonGroup);
            app.AscendingButton.Tooltip = {'A_3>A_2>A_1'};
            app.AscendingButton.Text = 'Ascending';
            app.AscendingButton.Value = true;
            app.DescendingButton = uiradiobutton(app.PAStensororderButtonGroup);
            app.DescendingButton.Tooltip = {'A_1>A_2>A_3'};
            app.DescendingButton.Text = 'Descending';
            app.AbsascendingButton = uiradiobutton(app.PAStensororderButtonGroup);
            app.AbsascendingButton.Tooltip = {'|A_3|>|A_2|>|A_1|'};
            app.AbsascendingButton.Text = 'Abs ascending';
            app.ButtonGroup = uibuttongroup(app.EuleranglesTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.RadiansButton = uiradiobutton(app.ButtonGroup);
            app.RadiansButton.Text = 'Radians';
            app.DegreesButton = uiradiobutton(app.ButtonGroup);
            app.DegreesButton.Text = 'Degrees';
            app.DegreesButton.Value = true;
            app.EqAngles = uitable(app.EuleranglesTab);
            app.EqAngles.ColumnName = {'Alpha'; 'Beta'; 'Gamma'};
            app.EqAngles.RowName = {};
            app.EquivalentanglesLabel = uilabel(app.EuleranglesTab);
            app.EquivalentanglesLabel.Text = 'Equivalent angles';
            app.PlotreferenceframeButton = uibutton(app.EuleranglesTab, 'push');
            app.PlotreferenceframeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotreferenceframeButtonPushed, true);
            app.PlotreferenceframeButton.Enable = 'off';
            app.PlotreferenceframeButton.Tooltip = {'Reference frame inherets information from the Rotation style, PAS tensor order, and Equivalent angles selection. And the left pane Tensor coordinates and Tensor scaling.  Press Molecular frame --> Euler to activate.'};
            app.PlotreferenceframeButton.Text = 'Plot reference frame';
            app.AngleselectionDropDownLabel = uilabel(app.EuleranglesTab);
            app.AngleselectionDropDownLabel.Text = 'Angle selection';
            app.AngleselectionDropDown = uidropdown(app.EuleranglesTab);
            app.AngleselectionDropDown.Items = {'Angle 1'};
            app.AngleselectionDropDown.ItemsData = 1;
            app.AngleselectionDropDown.Value = 1;
            app.Tensorselection1DropDownLabel_4 = uilabel(app.EuleranglesTab);
            app.Tensorselection1DropDownLabel_4.Text = 'X = Red, Y = Green, Z = Blue';
            app.ReferenceframebrightnessSliderLabel = uilabel(app.EuleranglesTab);
            app.ReferenceframebrightnessSliderLabel.Text = {'Reference frame'; 'brightness'};
            app.ReferenceframebrightnessSlider = uislider(app.EuleranglesTab);
            app.ReferenceframebrightnessSlider.Limits = [-1 1];
            app.ReferenceframebrightnessSlider.MajorTicks = [-1 0 1];
            app.DipolartensorTab = uitab(app.TabGroup);
            app.DipolartensorTab.Title = 'Dipolar tensor';
            app.GetdipolartensorButton = uibutton(app.DipolartensorTab, 'push');
            app.GetdipolartensorButton.ButtonPushedFcn = createCallbackFcn(app, @GetdipolartensorButtonPushed, true);
            app.GetdipolartensorButton.Tooltip = {'The dipolar tensor in normalized to [-1,-1,2].'};
            app.GetdipolartensorButton.Text = 'Get dipolar tensor';
            app.Atom1Label = uilabel(app.DipolartensorTab);
            app.Atom1Label.Text = 'Atom 1';
            app.xEditField_2Label = uilabel(app.DipolartensorTab);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.DipolartensorTab, 'numeric');
            app.xEditField_2.ValueDisplayFormat = '%.2f';
            app.xEditField_2.Value = -1.921142;
            app.yEditField_2Label = uilabel(app.DipolartensorTab);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.DipolartensorTab, 'numeric');
            app.yEditField_2.ValueDisplayFormat = '%.2f';
            app.yEditField_2.Value = 0.003155;
            app.zEditField_2Label = uilabel(app.DipolartensorTab);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.DipolartensorTab, 'numeric');
            app.zEditField_2.ValueDisplayFormat = '%.2f';
            app.zEditField_2.Value = -0.159581;
            app.Atom2Label = uilabel(app.DipolartensorTab);
            app.Atom2Label.Text = 'Atom 2';
            app.xEditField_3Label = uilabel(app.DipolartensorTab);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.DipolartensorTab, 'numeric');
            app.xEditField_3.ValueDisplayFormat = '%.2f';
            app.xEditField_3.Value = -2.203126;
            app.yEditField_3Label = uilabel(app.DipolartensorTab);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.DipolartensorTab, 'numeric');
            app.yEditField_3.ValueDisplayFormat = '%.2f';
            app.yEditField_3.Value = -0.92452;
            app.zEditField_3Label = uilabel(app.DipolartensorTab);
            app.zEditField_3Label.Text = 'z';
            app.zEditField_3 = uieditfield(app.DipolartensorTab, 'numeric');
            app.zEditField_3.ValueDisplayFormat = '%.2f';
            app.zEditField_3.Value = -0.543723;
            app.MagneticdipolartensorLabel = uilabel(app.DipolartensorTab);
            app.MagneticdipolartensorLabel.Text = 'Magnetic dipolar tensor';
            app.RelativetensorsTab = uitab(app.TabGroup);
            app.RelativetensorsTab.Tooltip = {'Uses the tensor selection drop downs from the list view.'};
            app.RelativetensorsTab.Title = 'Relative tensors';
            app.RotationschemeButtonGroup_2 = uibuttongroup(app.RelativetensorsTab);
            app.RotationschemeButtonGroup_2.Tooltip = {'Active rotation solutions: {a,b,g+Pi}, {a+Pi,Pi-b,Pi-g}, and {a+Pi,Pi-b,2Pi-g}'; 'Passive rotation solutions: {a+Pi,b,g }, {Pi-a,Pi-b,Pi+g}, and {2Pi-a,Pi-b,Pi+g}'};
            app.RotationschemeButtonGroup_2.Title = 'Rotation scheme';
            app.ActiveZYZButton_2 = uiradiobutton(app.RotationschemeButtonGroup_2);
            app.ActiveZYZButton_2.Text = 'Active Z-Y-Z';
            app.ActiveZYZButton_2.Value = true;
            app.PassiveZYZButton_2 = uiradiobutton(app.RotationschemeButtonGroup_2);
            app.PassiveZYZButton_2.Text = 'Passive Z-Y-Z';
            app.ActiveZXZButton_2 = uiradiobutton(app.RotationschemeButtonGroup_2);
            app.ActiveZXZButton_2.Text = 'Active Z-X-Z';
            app.PassiveZXZButton_2 = uiradiobutton(app.RotationschemeButtonGroup_2);
            app.PassiveZXZButton_2.Text = 'Passive Z-X-Z';
            app.Relativeanglesoftensor1intheframeoftensor2Button = uibutton(app.RelativetensorsTab, 'push');
            app.Relativeanglesoftensor1intheframeoftensor2Button.ButtonPushedFcn = createCallbackFcn(app, @Relativeanglesoftensor1intheframeoftensor2ButtonPushed, true);
            app.Relativeanglesoftensor1intheframeoftensor2Button.Tooltip = {'Tensor selection 1 in the frame of Tensor selection 2, identical to A^B in the Tensorview for matlab paper. Note that solutions are not unique for axially symmetric tensors. '};
            app.Relativeanglesoftensor1intheframeoftensor2Button.Text = 'Relative angles of tensor 1 in the frame of tensor 2';
            app.ButtonGroup_2 = uibuttongroup(app.RelativetensorsTab);
            app.RadiansButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.RadiansButton_2.Text = 'Radians';
            app.RadiansButton_2.Value = true;
            app.DegreesButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.DegreesButton_2.Text = 'Degrees';
            app.EqRot = uitable(app.RelativetensorsTab);
            app.EqRot.ColumnName = {'Alpha'; 'Beta'; 'Gamma'};
            app.EqRot.RowName = {};
            app.EquivalentrelativetensorsLabel = uilabel(app.RelativetensorsTab);
            app.EquivalentrelativetensorsLabel.Text = 'Equivalent relative tensors';
            app.AlphaEditField_2Label = uilabel(app.RelativetensorsTab);
            app.AlphaEditField_2Label.Text = 'Alpha';
            app.AlphaEditField_2 = uieditfield(app.RelativetensorsTab, 'numeric');
            app.AlphaEditField_2.ValueDisplayFormat = '%.2f';
            app.BetaEditField_2Label = uilabel(app.RelativetensorsTab);
            app.BetaEditField_2Label.Text = 'Beta';
            app.BetaEditField_2 = uieditfield(app.RelativetensorsTab, 'numeric');
            app.BetaEditField_2.ValueDisplayFormat = '%.2f';
            app.GammaEditField_2Label = uilabel(app.RelativetensorsTab);
            app.GammaEditField_2Label.Text = 'Gamma';
            app.GammaEditField_2 = uieditfield(app.RelativetensorsTab, 'numeric');
            app.GammaEditField_2.ValueDisplayFormat = '%.2f';
            app.SaverelativeanglesdatButton = uibutton(app.RelativetensorsTab, 'push');
            app.SaverelativeanglesdatButton.ButtonPushedFcn = createCallbackFcn(app, @SaverelativeanglesdatButtonPushed, true);
            app.SaverelativeanglesdatButton.Tooltip = {'Saves all 16 relative angle solutions in a .dat file. The file can be opened and edited with any text editor.'};
            app.SaverelativeanglesdatButton.Text = 'Save relative angles .dat';
            app.PAStensororder1ButtonGroup = uibuttongroup(app.RelativetensorsTab);
            app.PAStensororder1ButtonGroup.Title = 'PAS tensor order 1';
            app.AscendingButton_2 = uiradiobutton(app.PAStensororder1ButtonGroup);
            app.AscendingButton_2.Tooltip = {'A_3>A_2>A_1'};
            app.AscendingButton_2.Text = 'Ascending';
            app.AscendingButton_2.Value = true;
            app.DescendingButton_2 = uiradiobutton(app.PAStensororder1ButtonGroup);
            app.DescendingButton_2.Tooltip = {'A_1>A_2>A_3'};
            app.DescendingButton_2.Text = 'Descending';
            app.AbsascendingButton_2 = uiradiobutton(app.PAStensororder1ButtonGroup);
            app.AbsascendingButton_2.Tooltip = {'|A_3|>|A_2|>|A_1|'};
            app.AbsascendingButton_2.Text = 'Abs ascending';
            app.PAStensororder2ButtonGroup = uibuttongroup(app.RelativetensorsTab);
            app.PAStensororder2ButtonGroup.Title = 'PAS tensor order 2';
            app.AscendingButton_3 = uiradiobutton(app.PAStensororder2ButtonGroup);
            app.AscendingButton_3.Tooltip = {'A_3>A_2>A_1'};
            app.AscendingButton_3.Text = 'Ascending';
            app.AscendingButton_3.Value = true;
            app.DescendingButton_3 = uiradiobutton(app.PAStensororder2ButtonGroup);
            app.DescendingButton_3.Tooltip = {'A_1>A_2>A_3'};
            app.DescendingButton_3.Text = 'Descending';
            app.AbsascendingButton_3 = uiradiobutton(app.PAStensororder2ButtonGroup);
            app.AbsascendingButton_3.Tooltip = {'|A_3|>|A_2|>|A_1|'};
            app.AbsascendingButton_3.Text = 'Abs ascending';
            app.ReferenceframesTab = uitab(app.TabGroup);
            app.ReferenceframesTab.Title = 'Reference frames';
            app.RefTable = uitable(app.ReferenceframesTab);
            app.RefTable.ColumnName = {'Tensor 1'};
            app.RefTable.RowName = {};
            app.LoadreferenceframesdatButton = uibutton(app.ReferenceframesTab, 'push');
            app.LoadreferenceframesdatButton.ButtonPushedFcn = createCallbackFcn(app, @LoadreferenceframesdatButtonPushed, true);
            app.LoadreferenceframesdatButton.Tooltip = {'Loads a .dat reference frame file. Look at the space delimited example "RefFrames_ALA_Example.dat" to see the formating when making your own reference frame file.'};
            app.LoadreferenceframesdatButton.Text = 'Load reference frames .dat';
            app.PlottablereferenceframesButton = uibutton(app.ReferenceframesTab, 'push');
            app.PlottablereferenceframesButton.ButtonPushedFcn = createCallbackFcn(app, @PlottablereferenceframesButtonPushed, true);
            app.PlottablereferenceframesButton.Enable = 'off';
            app.PlottablereferenceframesButton.Tooltip = {'Plot all reference frames from the reference frame list. '};
            app.PlottablereferenceframesButton.Text = 'Plot table reference frames';
            app.EqAngles_2 = uitable(app.ReferenceframesTab);
            app.EqAngles_2.ColumnName = {'Alpha'; 'Beta'; 'Gamma'};
            app.EqAngles_2.RowName = {};
            app.AngleselectionDropDown_2Label = uilabel(app.ReferenceframesTab);
            app.AngleselectionDropDown_2Label.Text = 'Angle selection';
            app.AngleselectionDropDown_2 = uidropdown(app.ReferenceframesTab);
            app.AngleselectionDropDown_2.Items = {'Angle 1'};
            app.AngleselectionDropDown_2.ItemsData = 1;
            app.AngleselectionDropDown_2.Value = 1;
            app.RefselectionDropDownLabel = uilabel(app.ReferenceframesTab);
            app.RefselectionDropDownLabel.Text = 'Ref selection';
            app.RefselectionDropDown = uidropdown(app.ReferenceframesTab);
            app.RefselectionDropDown.Items = {'Ref frame 1'};
            app.RefselectionDropDown.ItemsData = 1;
            app.RefselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @RefselectionDropDownValueChanged, true);
            app.RefselectionDropDown.Value = 1;
            app.EquivalentanglesLabel_2 = uilabel(app.ReferenceframesTab);
            app.EquivalentanglesLabel_2.Text = 'Equivalent angles';
            app.PlotreferenceframeButton_2 = uibutton(app.ReferenceframesTab, 'push');
            app.PlotreferenceframeButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotreferenceframeButton_2Pushed, true);
            app.PlotreferenceframeButton_2.Enable = 'off';
            app.PlotreferenceframeButton_2.Tooltip = {'Plot reference frame inerets data from Ref selection and Angle selection.'};
            app.PlotreferenceframeButton_2.Text = 'Plot reference frame';
            app.Tensorselection1DropDownLabel_5 = uilabel(app.ReferenceframesTab);
            app.Tensorselection1DropDownLabel_5.Text = 'X = Red, Y = Green, Z = Blue';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.TextArea = uitextarea(app.AboutTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'   TensorView for Matlab is a GUI and script based tool to visualize tensors in a molecular context. TensorView for Matlab includes: Reading arbitrary .pdb and .xyz files for molecular visualization. Ovaloid and ellipsoid tensor visualisation. 3D model file conversion to .glb and .wrl. Euler angle and relative angle decoder. '; '   '; '   TensorView for Matlab is licenced by creative commons CC BY. https://creativecommons.org/licenses/'; 'Free to share and adapt. Give appropriate credits to authors:'; '------ Credits'; 'github.com/LeoSvenningsson/TensorViewforMatlab'; ''; 'mathworks.com/matlabcentral/fileexchange/55231-molecoule3d'; ''; 'onlinelibrary.wiley.com/doi/full/10.1002/mrc.4793'; ''; 'https://se.mathworks.com/matlabcentral/fileexchange/109264-matlab2glb'; ''; '   Version: 1.15'; ''; 'Authors: '; 'Dr. Leo Svenningsson (leo.svenningsson@fkem1.lu.se) '; 'Prof. Leonard Mueller (leonard.mueller@ucr.edu)'; 'Contributors:'; 'Dr. André Ludwig (aludwig@alumni.ethz.ch)'; 'Dmitri Sastin'; '------ '; '   TensorView for Matlab is a collaboration with works derrived from molecule3D.m (André Ludwig: mathworks.com/matlabcentral/fileexchange/55231-molecule3d), matlab2glb ( Dmitri Sastin: https://se.mathworks.com/matlabcentral/fileexchange/109264-matlab2glb), and TensorView (Prof. Leonard Mueller: https://onlinelibrary.wiley.com/doi/full/10.1002/mrc.4793 and https://sites.google.com/ucr.edu/Mueller/home/tensorview)'};
            app.ExportwrlButton = uibutton(app.UIFigure, 'push');
            app.ExportwrlButton.ButtonPushedFcn = createCallbackFcn(app, @ExportwrlButtonPushed, true);
            app.ExportwrlButton.Enable = 'off';
            app.ExportwrlButton.Text = 'Export .wrl';
            app.Panel = uipanel(app.UIFigure);
            app.LoadtensordatButton = uibutton(app.Panel, 'push');
            app.LoadtensordatButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtensordatButtonPushed, true);
            app.LoadtensordatButton.Tooltip = {'Loads a tensor list in .dat format'};
            app.LoadtensordatButton.Text = 'Load tensor .dat';
            app.TensorTable = uitable(app.Panel);
            app.TensorTable.ColumnName = {'Tensor 1'};
            app.TensorTable.RowName = {};
            app.TensorListButton = uibutton(app.Panel, 'push');
            app.TensorListButton.ButtonPushedFcn = createCallbackFcn(app, @TensorListButtonPushed, true);
            app.TensorListButton.Tooltip = {'Copies the left pane onformation to the tensor list.'};
            app.TensorListButton.Text = 'Tensor -->  List';
            app.TensorListButton_2 = uibutton(app.Panel, 'push');
            app.TensorListButton_2.ButtonPushedFcn = createCallbackFcn(app, @TensorListButton_2Pushed, true);
            app.TensorListButton_2.Tooltip = {'Copies Tensor selection 1 to the left pane.'};
            app.TensorListButton_2.Text = 'Tensor <-- List';
            app.SavetensordatButton = uibutton(app.Panel, 'push');
            app.SavetensordatButton.ButtonPushedFcn = createCallbackFcn(app, @SavetensordatButtonPushed, true);
            app.SavetensordatButton.Tooltip = {'Saves the tansor list in .dat format. Users can open the file with any text editor and manually edit the file if needed or for external scripts.'};
            app.SavetensordatButton.Text = 'Save tensor .dat';
            app.Tensorselection1DropDownLabel = uilabel(app.Panel);
            app.Tensorselection1DropDownLabel.Text = 'Tensor selection 1';
            app.Tensorselection1DropDown = uidropdown(app.Panel);
            app.Tensorselection1DropDown.Items = {'Tensor 1'};
            app.Tensorselection1DropDown.ItemsData = 1;
            app.Tensorselection1DropDown.Value = 1;
            app.Tensorselection2DropDownLabel = uilabel(app.Panel);
            app.Tensorselection2DropDownLabel.Text = 'Tensor selection 2';
            app.Tensorselection2DropDown = uidropdown(app.Panel);
            app.Tensorselection2DropDown.Items = {'Tensor 1'};
            app.Tensorselection2DropDown.ItemsData = 1;
            app.Tensorselection2DropDown.Value = 1;
            app.PlotalltensorsButton = uibutton(app.Panel, 'push');
            app.PlotalltensorsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotalltensorsButtonPushed, true);
            app.PlotalltensorsButton.Tooltip = {'Plots all tensors in the right pane tensor list.'};
            app.PlotalltensorsButton.Text = 'Plot all tensors';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExportforpdbButton = uibutton(app.Panel, 'push');
            app.ExportforpdbButton.ButtonPushedFcn = createCallbackFcn(app, @ExportforpdbButtonPushed, true);
            app.ExportforpdbButton.Tooltip = {'Saves atomic root-mean-square displacement tensor formating that can be used in Chimera.'};
            app.ExportforpdbButton.Text = 'Export for pdb';
            app.PlotallreferenceframesButton = uibutton(app.Panel, 'push');
            app.PlotallreferenceframesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotallreferenceframesButtonPushed, true);
            app.PlotallreferenceframesButton.Tooltip = {'Plot all reference frames has dependencies from "Reference frame brightness" and "PAS tensor order" and Scale. Will only get one solution out of 4 angles sets.'};
            app.PlotallreferenceframesButton.Text = 'Plot all reference frames';
            app.ExportglbButton = uibutton(app.UIFigure, 'push');
            app.ExportglbButton.ButtonPushedFcn = createCallbackFcn(app, @ExportglbButtonPushed, true);
            app.ExportglbButton.Enable = 'off';
            app.ExportglbButton.Text = 'Export .glb';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TensorView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end